classdef Lab08 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StartButton                    matlab.ui.control.Button
        KrokSlider                     matlab.ui.control.Slider
        KrokSliderLabel                matlab.ui.control.Label
        IndukcyjnouHEditField          matlab.ui.control.NumericEditField
        IndukcyjnouHEditFieldLabel     matlab.ui.control.Label
        PojemnokocowapFEditField       matlab.ui.control.NumericEditField
        PojemnokocowapFEditFieldLabel  matlab.ui.control.Label
        PojemnopoczpFEditField         matlab.ui.control.NumericEditField
        PojemnopoczpFEditFieldLabel    matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            %Radoslaw Jurczak, Krzysztof Jarek,
            %Bartłomiej Hajduk, Patryk Błoniarz
            
            start = app.PojemnopoczpFEditField.Value;
            koniec = app.PojemnokocowapFEditField.Value;
            indukcyjnosc = app.IndukcyjnouHEditField.Value;
            krok = app.KrokSlider.Value;
            
            tmp = floor((koniec-start)/krok);
            tab = zeros(2,tmp);
            counter = 1;
            
            for(i = start:krok:koniec)
                f = 1/(2*pi*sqrt(indukcyjnosc*10^(-6) * i*10^(-12)));
                f = f/10^6;
                tab(1,counter)=i;
                tab(2,counter)=f;
                counter = counter + 1;
            end
            plot(app.UIAxes,tab(1,:),tab(2,:),'-rd');
            
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'C [pF]')
            ylabel(app.UIAxes, 'f [MHz]')
            zlabel(app.UIAxes, 'Z')
            app.PojemnopoczpFEditFieldLabel = uilabel(app.UIFigure);
            app.PojemnopoczpFEditFieldLabel.Text = 'Pojemność pocz. [pF]';
            app.PojemnopoczpFEditField = uieditfield(app.UIFigure, 'numeric');
            app.PojemnokocowapFEditFieldLabel = uilabel(app.UIFigure);
            app.PojemnokocowapFEditFieldLabel.Text = 'Pojemność końcowa [pF]';
            app.PojemnokocowapFEditField = uieditfield(app.UIFigure, 'numeric');
            app.IndukcyjnouHEditFieldLabel = uilabel(app.UIFigure);
            app.IndukcyjnouHEditFieldLabel.Text = 'Indukcyjność [uH]';
            app.IndukcyjnouHEditField = uieditfield(app.UIFigure, 'numeric');
            app.KrokSliderLabel = uilabel(app.UIFigure);
            app.KrokSliderLabel.Text = 'Krok';
            app.KrokSlider = uislider(app.UIFigure);
            app.KrokSlider.Limits = [1 50];
            app.KrokSlider.Value = 1;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab08

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end